classdef cameraGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CameraGUIUIFigure           matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        ExitButton                  matlab.ui.control.Button
        SaveImageButton             matlab.ui.control.Button
        RunButton                   matlab.ui.control.Button
        ExposuretimeEditFieldLabel  matlab.ui.control.Label
        ExposuretimeEditField       matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        isRunning(1, 1) logical = 0; % is GUI currently running
        
        exposureTime(1, 1) single = 0;
        isExposureTimeChanged(1, 1) logical = 0;
        
        cameraObj(1, 1) = 0; % holds our camera object
        fig(1, 1) = figure(); % holding figure to display acquired image
        imSc;
    end
    
    methods (Access = private)
              
        function [] = StartAcquisition(app)
            app.isRunning = 1;
            app.RunButton.Text = 'Stop';
            
            app.fig = figure();
            app.imSc = imagesc(rand(500));
            
            while(app.isRunning)
                app.cameraObj.Acquire();
                set(app.imSc, 'cdata', app.cameraObj.img.Data);
                
                if app.isExposureTimeChanged
                    app.cameraObj.exposuretime = app.exposureTime;
                    app.isExposureTimeChanged = 0;
                end
                
                % acquire image
                
                % update imagesc
                
                % check if cam props changed, if so update
                
                % update callbacks and figures
                drawnow();
            end
        end
        
        function [] = StopAcquisition(app)
            app.isRunning = 0;
            app.RunButton.Text = 'Run';
        end
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, cameraObj)
            app.cameraObj = cameraObj;
            app.exposureTime = cameraObj.exposuretime;
        end

        % Button pushed function: RunButton
        function PushRunButton(app, event)
            if app.isRunning
                app.StopAcquisition();
            else
                app.StartAcquisition();
            end
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
            % save latest acquired image  
        end

        % Value changed function: ExposuretimeEditField
        function ExposuretimeEditFieldValueChanged(app, event)
            app.exposureTime = app.ExposuretimeEditField.Value;
            app.isExposureTimeChanged = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CameraGUIUIFigure = uifigure('Visible', 'off');
            app.CameraGUIUIFigure.Name = 'Camera GUI';
            app.GridLayout = uigridlayout(app.CameraGUIUIFigure);
            app.ExitButton = uibutton(app.GridLayout, 'push');
            app.ExitButton.Text = 'Exit';
            app.SaveImageButton = uibutton(app.GridLayout, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Text = 'Save Image';
            app.RunButton = uibutton(app.GridLayout, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @PushRunButton, true);
            app.RunButton.Text = 'Run';
            app.ExposuretimeEditFieldLabel = uilabel(app.GridLayout);
            app.ExposuretimeEditFieldLabel.Text = 'Exposure time';
            app.ExposuretimeEditField = uieditfield(app.GridLayout, 'numeric');
            app.ExposuretimeEditField.ValueChangedFcn = createCallbackFcn(app, @ExposuretimeEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.CameraGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cameraGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CameraGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CameraGUIUIFigure)
        end
    end
end